classdef ML3FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image               matlab.ui.control.Image
        Switch3             matlab.ui.control.ToggleSwitch
        Switch3Label        matlab.ui.control.Label
        Switch2             matlab.ui.control.ToggleSwitch
        Switch2Label        matlab.ui.control.Label
        Switch              matlab.ui.control.ToggleSwitch
        SwitchLabel         matlab.ui.control.Label
        KeepItSecretButton  matlab.ui.control.Button
        DonateButton        matlab.ui.control.Button
        SubmitButton        matlab.ui.control.Button
        Lamp                matlab.ui.control.Lamp
        LampLabel           matlab.ui.control.Label
        Label               matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        DropDownLabel       matlab.ui.control.Label
        TextArea            matlab.ui.control.TextArea
        TextAreaLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        stage
        finalChoice
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Startup)
        app.stage = 1;
        app.finalChoice = '';
        
        app.SubmitButton.Text = 'Submit';
        app.DonateButton.Text = 'Donate to Museum';
        app.KeepItSecretButton.Text = 'Keep it Secret';
        
        app.DropDown.Enable = 'off';
        app.SubmitButton.Enable = 'off';
        app.DonateButton.Enable = 'off';
        app.KeepItSecretButton.Enable = 'off';
        
        app.Lamp.Color = [0.8 0 0];
        app.Label.Text = 'Set the switches to unlock the door.';
        
        app.TextArea.Value = {
            'Secrets of the Phoenicians';
            ' ';
            'Its 8pm. You tripped over a tile in the museum basement.';
            'You find a tablet. It says this in Arabic';
            ' ';
            'PUZZLE 1';
            '"first and last open the door, the middle keeps it shut"';
            '(Switch 1 = ON, Switch 2 = OFF, Switch 3 = ON)';
            'Lamp turns green when correct. Then hit Submit.'
                };
        end

        % Value changed function: Switch, Switch2, Switch3
        function SwitchValueChanged(app, event)
            if app.stage ~= 1
                return
            end
            
            s1 = strcmp(app.Switch.Value,  'On');
            s2 = strcmp(app.Switch2.Value, 'Off');
            s3 = strcmp(app.Switch3.Value, 'On');
            
            if s1 && s2 && s3
                app.Lamp.Color = [0 0.8 0];
                app.Label.Text = 'Something clicks. Hit Submit.';
                app.SubmitButton.Enable = 'on';
            else
                app.Lamp.Color = [0.8 0 0];
                app.Label.Text = 'Nothing happens.';
                app.SubmitButton.Enable = 'off';
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            if app.stage == 1
                app.stage = 2;
                app.SubmitButton.Enable = 'on';
                app.DropDown.Enable = 'on';
                app.Lamp.Color = [0.8 0 0];
                app.Label.Text = 'Choose your path, then hit Submit.';
            
                app.TextArea.Value = [app.TextArea.Value; {
                'Behind a tablet is a map pointing to the city of Byblos';
                'Theres a note in the bottom: "river in the morning, never the coast at noon"';
                ' ';
                'Puzzle 2: Pick your route from the dropdown. Hit Submit.'
                }];
                return
            end
            
            if app.stage == 2
                chosen = app.DropDown.Value;
            
                if strcmp(chosen,'River Road (North)')
                    app.finalChoice = 'good';
                    app.Label.Text = 'You made it. Now what?';
                    app.TextArea.Value = [app.TextArea.Value; {
                        ' ';
                        'You hike for four hours and ur shoes get ruined.';
                        'You find a stone door.';
                        'Inside you a clay scroll, sealed with wax.';
                        ' ';
                        'FINAL CHOICE';
                        'Hand it to the National Museum.';
                        'Or take it. Keep it safe yourself.';
                        'Nobody else knows youre here.'
                    }];
            
                elseif strcmp(chosen, 'Coastal Road')
                    app.finalChoice = 'damaged';
                    app.Label.Text = 'You made it. Barely.';
                    app.TextArea.Value = [app.TextArea.Value; {
                        ' ';
                        '---';
                        'Uh oh, you ran out of water and are lost in the city of Jounieh';
                        'You ran out of water, got lost near Jounieh.';
                        ' ';
                        'The scroll is damaged';
                        'Some of it is unreadable now.';
                        ' ';
                        'FINAL CHOICE';
                        'Should I keep it? Its an ancient piece';
                        'Museum, or your apartment?'
                    }];
            
                else
                    app.Image.ImageSource = 'C:\Users\georg\Downloads\deadend.png';
                    app.Label.Text = 'Dead end. Try again.';
                    return
                end
         
                app.stage = 3;
                app.DropDown.Enable = 'off';
                app.SubmitButton.Enable = 'off';
                app.DonateButton.Enable = 'on';
                app.KeepItSecretButton.Enable = 'on';
            end
        end

        % Button pushed function: DonateButton
        function DonateButtonPushed(app, event)
            app.DonateButton.Enable = 'off';
            app.KeepItSecretButton.Enable = 'off';
            
            if strcmp(app.finalChoice, 'good')
                app.Image.ImageSource = 'C:\Users\georg\Downloads\ending1.png';
                app.TextArea.Value = [app.TextArea.Value; {
                    ' ';
                    'ENDING 1: They name the exhibit after you.';
                    ' ';
                    'End';
                }];
               
            else
                app.Image.ImageSource = 'C:\Users\georg\Downloads\ending2.png';
                app.TextArea.Value = [app.TextArea.Value; {
                    ' ';
                    '---';
                    'ENDING 2: Good enough, mostly.';
                    ' ';
                    'The museum takes it. They try to restore whats left and 1/3 was left';
                    ' ';
                    'End'
                }];
                
            end
            app.Label.Text = 'End';
        end

        % Button pushed function: KeepItSecretButton
        function KeepItSecretButtonPushed(app, event)
            app.DonateButton.Enable = 'off';
            app.KeepItSecretButton.Enable = 'off';
            app.TextArea.Value = [app.TextArea.Value; {
                ' ';
                '---';
                'ENDING 3: You take it home.';
                ' ';
                'Youre riddled with guilt forever. Youre stuck with the scroll in your apartment. It becomes damaged on the way home';
                ' ';
                'END'
                
            }];
            app.Image.ImageSource = 'C:\Users\georg\Downloads\ending3.png';
            app.Label.Text = 'End';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'River Road (North)', 'Coastal Road', 'Mountain Trail'};
            app.DropDown.Value = 'River Road (North)';
            app.Label = uilabel(app.UIFigure);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.DonateButton = uibutton(app.UIFigure, 'push');
            app.DonateButton.ButtonPushedFcn = createCallbackFcn(app, @DonateButtonPushed, true);
            app.DonateButton.Text = 'Donate';
            app.KeepItSecretButton = uibutton(app.UIFigure, 'push');
            app.KeepItSecretButton.ButtonPushedFcn = createCallbackFcn(app, @KeepItSecretButtonPushed, true);
            app.KeepItSecretButton.Text = 'Keep It Secret';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch2Label = uilabel(app.UIFigure);
            app.Switch2Label.Text = 'Switch2';
            app.Switch2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch3Label = uilabel(app.UIFigure);
            app.Switch3Label.Text = 'Switch3';
            app.Switch3 = uiswitch(app.UIFigure, 'toggle');
            app.Switch3.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ML3FINAL(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end